classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label     matlab.ui.control.Label
        Label_4   matlab.ui.control.Label
        Label_6   matlab.ui.control.Label
        Label_5   matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        Label_7   matlab.ui.control.Label
        Label_11  matlab.ui.control.Label
        Label_8   matlab.ui.control.Label
        Label_3   matlab.ui.control.Label
        Label_9   matlab.ui.control.Label
        Label_12  matlab.ui.control.Label
        Label_10  matlab.ui.control.Label
        Button    matlab.ui.control.Button
        Label_13  matlab.ui.control.Label
        Label_14  matlab.ui.control.Label
        Label_15  matlab.ui.control.Label
        Label_16  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            s1 = str2num(app.Label_9.Text);
            s2 = str2num(app.Label_10.Text);
            s3 = str2num(app.Label_12.Text);
            s4 = s1+s2+s3;
            s5 = num2str(s4);
            s6 = 'Поздравляем, вы прошли';
            s7 = 'Увы, вы не прошли!';
            s10 = str2num(app.Label_14.Text);
            app.Label_16.Text = s5;
            if s4 > s10
                helpdlg(s6, 'Результат');
            else
                helpdlg(s7, 'Результат');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ФИО';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Петров';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Петрович';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Петр';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Предмет';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Математика';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'Информатика';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = {'Русский язык'; ''};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Баллы';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '99';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '94';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '94';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычислить';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = 'Проходной балл:';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '271';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = 'Сумма баллов:';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end