classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        UIAxes      matlab.ui.control.UIAxes
        Button      matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
        Button_3    matlab.ui.control.Button
        Button_4    matlab.ui.control.Button
        expx2Label  matlab.ui.control.Label
        sinxLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            x = (-2:0.2:2);
            y = exp(-x.^2);
            hold(app.UIAxes)
            global P
            P = plot(app.UIAxes, x, y);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            x1 = (0:0.01:4*pi);
            y1 = sin(x1);
            hold(app.UIAxes);
            global P1
            P1 = plot(app.UIAxes, x1, y1);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global P
            delete(P);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            global P1
            delete(P1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Очистить';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'Построить';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'Очистить';
            app.expx2Label = uilabel(app.UIFigure);
            app.expx2Label.Text = 'График exp(x^2)';
            app.sinxLabel = uilabel(app.UIFigure);
            app.sinxLabel.Text = 'График sin(x)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end